classdef CDmotor_SS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CDMotorSystemIdentificationforStateSpacebuildingLabel  matlab.ui.control.Label
        ExportPNGsandLogsButton   matlab.ui.control.Button
        RefreshCOMButton          matlab.ui.control.Button
        COMSelectorDropDown       matlab.ui.control.DropDown
        COMSelectorDropDownLabel  matlab.ui.control.Label
        PWMlistEditField          matlab.ui.control.EditField
        PWMlistEditField_2Label   matlab.ui.control.Label
        AngularvelocidtywfilterCutofffrequencyEditField  matlab.ui.control.NumericEditField
        AngularvelocidtywfilterCutofffrequencyEditFieldLabel  matlab.ui.control.Label
        CurrentIfilterCutofffrequencyEditField  matlab.ui.control.NumericEditField
        CurrentIfilterCutofffrequencyEditFieldLabel  matlab.ui.control.Label
        StatespacemodelTextArea   matlab.ui.control.TextArea
        continuoustimestatespacemodelLabel  matlab.ui.control.Label
        BuildSSButton             matlab.ui.control.Button
        DoneButtonAngularVel      matlab.ui.control.Button
        DoneButtonCurrent         matlab.ui.control.Button
        StartFilterButton         matlab.ui.control.Button
        StartLogButton            matlab.ui.control.Button
        VoltageinEditField        matlab.ui.control.NumericEditField
        VoltageinEditFieldLabel   matlab.ui.control.Label
        TsmsEditField             matlab.ui.control.NumericEditField
        TsmsEditFieldLabel        matlab.ui.control.Label
        ModelFitAngularVel        matlab.ui.control.UIAxes
        ModelFitCurrent           matlab.ui.control.UIAxes
        LiveAngularVelFiltering   matlab.ui.control.UIAxes
        ArmatureCurrent           matlab.ui.control.UIAxes
        LiveCurrentFIltering      matlab.ui.control.UIAxes
        AngularVelocity           matlab.ui.control.UIAxes
        MotorVoltage              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Data storage for the pipeline
        Time               % time vector from log
        PWM_i              % duty [%]
        Omega_o            % omega [rad/s]
        I_o                % current [A]
        Ts                 % sampling time [s]
        t_filtered         % evenly spaced time (same as Ts-based)

        I_filt             % filtered current
        w_filt             % filtered omega

        Mimo_SS_input      % voltage input for ID
        Mimo_SS_output     % [I_filt, w_filt]

        model_MIMO         % identified state-space model

        currentFilterActive logical = false
        omegaFilterActive  logical = false

    end

    methods (Access = private)
    
        function updateCurrentFilterPlot(app)
            if isempty(app.I_o) || isempty(app.t_filtered)
                return;
            end
    
            a = app.CurrentIfilterCutofffrequencyEditField.Value;
            if a <= 0
                % If invalid, just show original
                cla(app.LiveCurrentFIltering);
                plot(app.LiveCurrentFIltering, app.t_filtered, app.I_o, ...
                    'Color', [0.7 0.7 0.7], 'DisplayName', 'Original');
                grid(app.LiveCurrentFIltering, 'on');
                xlabel(app.LiveCurrentFIltering, 'Time [s]');
                ylabel(app.LiveCurrentFIltering, 'Current [A]');
                title(app.LiveCurrentFIltering, 'Interactive Filtering - I_o');
                legend(app.LiveCurrentFIltering, 'show');
                app.I_filt = app.I_o;
                return;
            end
    
            % 2nd-order LPF: G(s) = a^2 / (s^2 + 2*a*s + a^2)
            filtro = tf([a^2], [1 2*a a^2]);
            app.I_filt = lsim(filtro, app.I_o, app.t_filtered);
    
            % Redraw axes: original + filtered
            cla(app.LiveCurrentFIltering);
            hold(app.LiveCurrentFIltering, 'on');
            plot(app.LiveCurrentFIltering, app.t_filtered, app.I_o, ...
                'Color', [0.7 0.7 0.7], 'DisplayName', 'Original');
            plot(app.LiveCurrentFIltering, app.t_filtered, app.I_filt, ...
                'DisplayName', sprintf('a = %.0f', a));
            grid(app.LiveCurrentFIltering, 'on');
            xlabel(app.LiveCurrentFIltering, 'Time [s]');
            ylabel(app.LiveCurrentFIltering, 'Current [A]');
            title(app.LiveCurrentFIltering, 'Interactive Filtering - I_o');
            legend(app.LiveCurrentFIltering, 'show');
            hold(app.LiveCurrentFIltering, 'off');
        end
    
        function updateOmegaFilterPlot(app)
            if isempty(app.Omega_o) || isempty(app.t_filtered)
                return;
            end
    
            a = app.AngularvelocidtywfilterCutofffrequencyEditField.Value;
            if a <= 0
                % If invalid, just show original
                cla(app.LiveAngularVelFiltering);
                plot(app.LiveAngularVelFiltering, app.t_filtered, app.Omega_o, ...
                    'Color', [0.7 0.7 0.7], 'DisplayName', 'Original');
                grid(app.LiveAngularVelFiltering, 'on');
                xlabel(app.LiveAngularVelFiltering, 'Time [s]');
                ylabel(app.LiveAngularVelFiltering, '\omega [rad/s]');
                title(app.LiveAngularVelFiltering, 'Interactive Filtering - \omega');
                legend(app.LiveAngularVelFiltering, 'show');
                app.w_filt = app.Omega_o;
                return;
            end
    
            % 2nd-order LPF: G(s) = a^2 / (s^2 + 2*a*s + a^2)
            filtro = tf([a^2], [1 2*a a^2]);
            app.w_filt = lsim(filtro, app.Omega_o, app.t_filtered);
    
            % Redraw axes: original + filtered
            cla(app.LiveAngularVelFiltering);
            hold(app.LiveAngularVelFiltering, 'on');
            plot(app.LiveAngularVelFiltering, app.t_filtered, app.Omega_o, ...
                'Color', [0.7 0.7 0.7], 'DisplayName', 'Original');
            plot(app.LiveAngularVelFiltering, app.t_filtered, app.w_filt, ...
                'DisplayName', sprintf('a = %.0f', a));
            grid(app.LiveAngularVelFiltering, 'on');
            xlabel(app.LiveAngularVelFiltering, 'Time [s]');
            ylabel(app.LiveAngularVelFiltering, '\omega [rad/s]');
            title(app.LiveAngularVelFiltering, 'Interactive Filtering - \omega');
            legend(app.LiveAngularVelFiltering, 'show');
            hold(app.LiveAngularVelFiltering, 'off');
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Maximize window on startup
            app.UIFigure.WindowState = 'maximized';
        
            % Enable responsive resizing of all child components
            app.UIFigure.AutoResizeChildren = 'on';
        end

        % Value changed function: TsmsEditField
        function TsmsEditFieldValueChanged(app, event)
            value = app.TsmsEditField.Value;
            
        end

        % Button pushed function: StartLogButton
        function StartLogButtonPushed(app, event)
            % === Read Ts and PWM list from GUI ===
            Ts_ms = app.TsmsEditField.Value;
        
            raw = app.PWMlistEditField.Value;   % TEXT field input
        
            % Convert text to numeric array
            try
                pwm_list = str2num(raw); %#ok<ST2NM>
            catch
                pwm_list = [];
            end
        
            % === Validate PWM list ===
            if numel(pwm_list) ~= 3
                uialert(app.UIFigure, ...
                    'PWM list must contain exactly 3 numeric values, e.g. "30 50 80".', ...
                    'Invalid PWM list');
                return;
            end
            
            if any(pwm_list < 0 | pwm_list > 100)
                uialert(app.UIFigure, ...
                    'PWM values must be between 0 and 100.', ...
                    'Invalid PWM Range');
                return;
            end
        
            % === Validate Ts ===
            if Ts_ms <= 0
                uialert(app.UIFigure, 'Ts_ms must be > 0.', 'Invalid Ts');
                return;
            end
        
            % === Serial settings ===
            port = app.COMSelectorDropDown.Value;
            if strcmp(port, 'No ports found')
                uialert(app.UIFigure, 'No COM port selected.', 'Error');
                return;
            end
            
            baud = 921600;
        
            % === Log via ESP32 ===
            try
                [app.Time, app.PWM_i, app.Omega_o, app.I_o, app.Ts, app.t_filtered] = ...
                    CDMotor_SS_Log(Ts_ms, pwm_list, port, baud);
            catch ME
                uialert(app.UIFigure, ME.message, 'Logging Error');
                return;
            end
        
            % === Plot results ===
            cla(app.MotorVoltage);
            plot(app.MotorVoltage, app.Time, app.PWM_i, 'LineWidth', 1.2);
            grid(app.MotorVoltage, 'on');
            xlabel(app.MotorVoltage, 'Time [s]');
            ylabel(app.MotorVoltage, 'PWM [%]');
            title(app.MotorVoltage, 'Motor PWM Input');
        
            cla(app.AngularVelocity);
            plot(app.AngularVelocity, app.Time, app.Omega_o, 'LineWidth', 1.2);
            grid(app.AngularVelocity, 'on');
            xlabel(app.AngularVelocity, 'Time [s]');
            ylabel(app.AngularVelocity, '\omega [rad/s]');
            title(app.AngularVelocity, 'Angular Velocity');
        
            cla(app.ArmatureCurrent);
            plot(app.ArmatureCurrent, app.Time, app.I_o, 'LineWidth', 1.2);
            grid(app.ArmatureCurrent, 'on');
            xlabel(app.ArmatureCurrent, 'Time [s]');
            ylabel(app.ArmatureCurrent, 'Current [A]');
            title(app.ArmatureCurrent, 'Armature Current');
        
            % === Reset filters ===
            app.I_filt = [];
            app.w_filt = [];
            app.currentFilterActive = false;
            app.omegaFilterActive   = false;
        
            disp('Stage 1 (Log) completed and data stored in app.');
        end

        % Button down function: MotorVoltage
        function MotorVoltageButtonDown(app, event)
            
        end

        % Button down function: AngularVelocity
        function AngularVelocityButtonDown(app, event)
            
        end

        % Button down function: ArmatureCurrent
        function ArmatureCurrentButtonDown(app, event)
            
        end

        % Value changed function: CurrentIfilterCutofffrequencyEditField
        function CurrentIfilterCutofffrequencyEditFieldValueChanged(app, event)
            if app.currentFilterActive
                app.updateCurrentFilterPlot();
            end 
        end

        % Button pushed function: DoneButtonCurrent
        function DoneButtonCurrentPushed(app, event)
            app.currentFilterActive = false;
            disp('Current filtering completed (I_filt frozen).');
        end

        % Button down function: LiveCurrentFIltering
        function LiveCurrentFIlteringButtonDown(app, event)
            
        end

        % Button pushed function: StartFilterButton
        function StartFilterButtonPushed(app, event)
             % Make sure we have logged data
            if isempty(app.Time) || isempty(app.I_o) || isempty(app.Omega_o)
                uialert(app.UIFigure, ...
                    'Please run "Start Log" first.', ...
                    'No Data Available');
                return;
            end
        
            % Build time vector from Ts (we also have t_filtered from logging)
            t = app.t_filtered;
            app.I_o = app.I_o;
            app.Omega_o = app.Omega_o;
        
            % Activate filtering mode
            app.currentFilterActive = true;
            app.omegaFilterActive   = true;
        
            % Default filtered signals = original (before any cutoff is applied)
            app.I_filt = app.I_o;
            app.w_filt = app.Omega_o;
        
            % --- Plot Current filtering on UIAxes4 ---
            cla(app.LiveCurrentFIltering);
            hold(app.LiveCurrentFIltering, 'on');
            plot(app.LiveCurrentFIltering, t, app.I_o, 'Color', [0.7 0.7 0.7], 'DisplayName', 'Original');
            xlabel(app.LiveCurrentFIltering, 'Time [s]');
            ylabel(app.LiveCurrentFIltering, 'Current [A]');
            title(app.LiveCurrentFIltering, 'Interactive Filtering - I_o');
            grid(app.LiveCurrentFIltering, 'on');
            legend(app.LiveCurrentFIltering, 'show');
            hold(app.LiveCurrentFIltering, 'off');
        
            % --- Plot Omega filtering on UIAxes5 ---
            cla(app.LiveAngularVelFiltering);
            hold(app.LiveAngularVelFiltering, 'on');
            plot(app.LiveAngularVelFiltering, t, app.Omega_o, 'Color', [0.7 0.7 0.7], 'DisplayName', 'Original');
            xlabel(app.LiveAngularVelFiltering, 'Time [s]');
            ylabel(app.LiveAngularVelFiltering, '\omega [rad/s]');
            title(app.LiveAngularVelFiltering, 'Interactive Filtering - \omega');
            grid(app.LiveAngularVelFiltering, 'on');
            legend(app.LiveAngularVelFiltering, 'show');
            hold(app.LiveAngularVelFiltering, 'off');
        
            % Optionally apply initial cutoff values if they are > 0
            app.updateCurrentFilterPlot();
            app.updateOmegaFilterPlot();
        
            disp('Stage 2 (Filter) initialized. Adjust cutoff values and press Done.');
        end

        % Value changed function: 
        % AngularvelocidtywfilterCutofffrequencyEditField
        function AngularvelocidtywfilterCutofffrequencyEditFieldValueChanged(app, event)
            if app.omegaFilterActive
                app.updateOmegaFilterPlot();
            end
        end

        % Button pushed function: DoneButtonAngularVel
        function DoneButtonAngularVelPushed(app, event)
            app.omegaFilterActive = false;
            disp('Omega filtering completed (w_filt frozen).');
        end

        % Button down function: LiveAngularVelFiltering
        function LiveAngularVelFilteringButtonDown(app, event)
            
        end

        % Button pushed function: BuildSSButton
        function BuildSSButtonPushed(app, event)
             % Check that filtering has at least produced something
            if isempty(app.I_filt) || isempty(app.w_filt)
                uialert(app.UIFigure, ...
                    'Please run filtering and press "Done" for both signals first.', ...
                    'No Filtered Data');
                return;
            end
        
            % Read V_in and check
            V_in = app.VoltageinEditField.Value;
            if V_in < 0 || V_in > 24
                uialert(app.UIFigure, 'V_in must be between 0 and 24 V.', ...
                    'Invalid V_in');
                return;
            end
        
            if isempty(app.PWM_i) || isempty(app.Ts)
                uialert(app.UIFigure, 'Logging data or Ts is missing.', ...
                    'Missing Data');
                return;
            end
        
            % Prepare MIMO variables
            app.Mimo_SS_input  = (V_in * app.PWM_i) / 100;       % voltage
            app.Mimo_SS_output = [app.I_filt(:), app.w_filt(:)]; % [current, omega]
        
            u = app.Mimo_SS_input(:);
            y = app.Mimo_SS_output;
            app.Ts = app.Ts;
        
            % Build iddata
            data = iddata(y, u, app.Ts);
        
            % Estimate continuous-time state-space model with 2 states
            try
                app.model_MIMO = ssest(data, 2, 'Ts', 0);  % continuous-time
            catch ME
                uialert(app.UIFigure, ME.message, 'System ID Error');
                return;
            end
        
            app.model_MIMO = app.model_MIMO;
        
            % Extract matrices
            [A, B, C, D] = ssdata(app.model_MIMO);
            fitPercent = app.model_MIMO.Report.Fit.FitPercent;
            FPE        = app.model_MIMO.Report.Fit.FPE;
        
            % --- Plot fits on UIAxes6 (current) and UIAxes7 (omega) using lsim ---
            t = app.t_filtered;
            y_hat = lsim(app.model_MIMO, u, t);  % Nx2: [I_hat, w_hat]

            offset = y(1,:) - y_hat(1,:);   % row vector [offset_I, offset_w]
            y_hat = y_hat + offset;         % shift model up/down to match start
        
            % Current fit
            cla(app.ModelFitCurrent);
            hold(app.ModelFitCurrent, 'on');
            plot(app.ModelFitCurrent, t, y(:,1), 'DisplayName', 'Measured I_f_i_l_t');
            plot(app.ModelFitCurrent, t, y_hat(:,1), '--', 'DisplayName', 'Model I');
            grid(app.ModelFitCurrent, 'on');
            xlabel(app.ModelFitCurrent, 'Time [s]');
            ylabel(app.ModelFitCurrent, 'Current [A]');
            title(app.ModelFitCurrent, 'Model vs Measured - Current');
            legend(app.ModelFitCurrent, 'show');
            hold(app.ModelFitCurrent, 'off');
        
            % Omega fit
            cla(app.ModelFitAngularVel);
            hold(app.ModelFitAngularVel, 'on');
            plot(app.ModelFitAngularVel, t, y(:,2), 'DisplayName', 'Measured \omega_f_i_l_t');
            plot(app.ModelFitAngularVel, t, y_hat(:,2), '--', 'DisplayName', 'Model \omega');
            grid(app.ModelFitAngularVel, 'on');
            xlabel(app.ModelFitAngularVel, 'Time [s]');
            ylabel(app.ModelFitAngularVel, '\omega [rad/s]');
            title(app.ModelFitAngularVel, 'Model vs Measured - \omega');
            legend(app.ModelFitAngularVel, 'show');
            hold(app.ModelFitAngularVel, 'off');
        
            % --- Save model to file and log (as in your script) ---
            model_MIMO = app.model_MIMO;
            Ts = app.Ts;
            save('CDMotor_SS_Model.mat', 'model_MIMO', 'A', 'B', 'C', 'D', 'Ts');

        
            fid = fopen('SS_log.txt', 'w');
            fprintf(fid, "=== CDMotor_SS_Build Pipeline Log ===\n");
            fprintf(fid, "Date: %s\n\n", char(datetime("now", "Format", "yyyy-MM-dd HH:mm:ss")));
            fprintf(fid, "Ts (s): %.6f\n\n", app.Ts);
        
            fprintf(fid, "A = \n"); fprintf(fid, "%g ", A); fprintf(fid, "\n\n");
            fprintf(fid, "B = \n"); fprintf(fid, "%g ", B); fprintf(fid, "\n\n");
            fprintf(fid, "C = \n"); fprintf(fid, "%g ", C); fprintf(fid, "\n\n");
            fprintf(fid, "D = \n"); fprintf(fid, "%g ", D); fprintf(fid, "\n\n");
        
            fprintf(fid, "Fit Percentages:\n");
            fprintf(fid, "%s\n\n", mat2str(fitPercent));
        
            fprintf(fid, "FPE: %.6g\n", FPE);
            fclose(fid);
        
            % --- Write readable summary into the TextArea ---
            txtA = strtrim(evalc('disp(A)'));
            txtB = strtrim(evalc('disp(B)'));
            txtC = strtrim(evalc('disp(C)'));
            txtD = strtrim(evalc('disp(D)'));
        
            lines = {};

            lines(end+1) = {'=== Continuous-time State-space Model ==='};
            lines(end+1) = {sprintf('Ts = %.6f s', app.Ts)};
            lines(end+1) = {' '};
            
            % A matrix
            lines(end+1) = {'A ='};
            A_lines = cellstr(txtA);     % column → row
            lines = [lines, A_lines'];   % append as row
            lines(end+1) = {' '};
            
            % B matrix
            lines(end+1) = {'B ='};
            B_lines = cellstr(txtB);
            lines = [lines, B_lines'];
            lines(end+1) = {' '};
            
            % C matrix
            lines(end+1) = {'C ='};
            C_lines = cellstr(txtC);
            lines = [lines, C_lines'];
            lines(end+1) = {' '};
            
            % D matrix
            lines(end+1) = {'D ='};
            D_lines = cellstr(txtD);
            lines = [lines, D_lines'];
            lines(end+1) = {' '};
            
            lines(end+1) = {sprintf('Fit Percentages (Current, Omega): %s', mat2str(fitPercent))};
            lines(end+1) = {sprintf('FPE: %.6g', FPE)};

        
            app.StatespacemodelTextArea.Value = lines;
        
            disp('Stage 3 (SS Build) completed. Model saved and text area updated.');
        end

        % Button down function: ModelFitCurrent
        function ModelFitCurrentButtonDown(app, event)
            
        end

        % Value changed function: StatespacemodelTextArea
        function StatespacemodelTextAreaValueChanged(app, event)
            value = app.StatespacemodelTextArea.Value;
            
        end

        % Button down function: ModelFitAngularVel
        function ModelFitAngularVelButtonDown(app, event)
            
        end

        % Value changed function: PWMlistEditField
        function PWMlistEditFieldValueChanged(app, event)
            value = app.PWMlistEditField.Value;
            
        end

        % Button pushed function: RefreshCOMButton
        function RefreshCOMButtonPushed(app, event)
            ports = serialportlist("available");
            if isempty(ports)
                app.COMSelectorDropDown.Items = {'No ports found'};
                app.COMSelectorDropDown.Value = 'No ports found';
            else
                app.COMSelectorDropDown.Items = ports;
                app.COMSelectorDropDown.Value = ports{1};
            end
        end

        % Button pushed function: ExportPNGsandLogsButton
        function ExportPNGsandLogsButtonPushed(app, event)
            % ================================
            % 1. Create Export Folder
            % ================================
            timestamp = char(datetime("now","Format","yyyy-MM-dd_HH-mm-ss"));
            exportFolder = fullfile(pwd, ['Export_', timestamp]);
            mkdir(exportFolder);
        
            % ================================
            % 2. Export all UIAxes as PNG
            % ================================
            try
                exportgraphics(app.MotorVoltage, fullfile(exportFolder, 'MotorVoltage.png'));
                exportgraphics(app.AngularVelocity, fullfile(exportFolder, 'AngularVelocity.png'));
                exportgraphics(app.ArmatureCurrent, fullfile(exportFolder, 'ArmatureCurrent.png'));
                exportgraphics(app.LiveCurrentFIltering, fullfile(exportFolder, 'LiveCurrentFiltering.png'));
                exportgraphics(app.LiveAngularVelFiltering, fullfile(exportFolder, 'LiveAngularVelFiltering.png'));
                exportgraphics(app.ModelFitCurrent, fullfile(exportFolder, 'ModelFit_Current.png'));
                exportgraphics(app.ModelFitAngularVel, fullfile(exportFolder, 'ModelFit_AngularVel.png'));
            catch ME
                uialert(app.UIFigure, ['Error exporting PNGs: ' ME.message], 'Export Error');
                return;
            end
        
            % ================================
            % 3. Export State-Space Text Log
            % ================================
            try
                logFile = fullfile(exportFolder, 'StateSpaceBuildResults.txt');
                fid = fopen(logFile, 'w');
        
                lines = app.StatespacemodelTextArea.Value;
                for i = 1:numel(lines)
                    fprintf(fid, '%s\n', lines{i});
                end
        
                fclose(fid);
            catch ME
                uialert(app.UIFigure, ['Error saving log file: ' ME.message], 'Export Error');
                return;
            end
        
            % ================================
            % 4. Notify User
            % ================================
            msg = sprintf('Exports complete!\nFolder:\n%s', exportFolder);
            uialert(app.UIFigure, msg, 'Export Successful');
        
            disp(['Export completed to: ' exportFolder]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MotorVoltage = uiaxes(app.UIFigure);
            title(app.MotorVoltage, 'Motor Voltage')
            xlabel(app.MotorVoltage, 'Time (seconds)')
            ylabel(app.MotorVoltage, 'PWM (%)')
            zlabel(app.MotorVoltage, 'Z')
            app.MotorVoltage.ButtonDownFcn = createCallbackFcn(app, @MotorVoltageButtonDown, true);
            app.AngularVelocity = uiaxes(app.UIFigure);
            title(app.AngularVelocity, 'Angular velocity')
            xlabel(app.AngularVelocity, 'Time (seconds)')
            ylabel(app.AngularVelocity, 'rad/s')
            zlabel(app.AngularVelocity, 'Z')
            app.AngularVelocity.ButtonDownFcn = createCallbackFcn(app, @AngularVelocityButtonDown, true);
            app.LiveCurrentFIltering = uiaxes(app.UIFigure);
            title(app.LiveCurrentFIltering, 'Live Current Filtering')
            xlabel(app.LiveCurrentFIltering, 'Time (seconds)')
            ylabel(app.LiveCurrentFIltering, 'Current (A)')
            zlabel(app.LiveCurrentFIltering, 'Z')
            app.LiveCurrentFIltering.ButtonDownFcn = createCallbackFcn(app, @LiveCurrentFIlteringButtonDown, true);
            app.ArmatureCurrent = uiaxes(app.UIFigure);
            title(app.ArmatureCurrent, 'Armature Current')
            xlabel(app.ArmatureCurrent, 'Time (seconds)')
            ylabel(app.ArmatureCurrent, 'Current (A)')
            zlabel(app.ArmatureCurrent, 'Z')
            app.ArmatureCurrent.ButtonDownFcn = createCallbackFcn(app, @ArmatureCurrentButtonDown, true);
            app.LiveAngularVelFiltering = uiaxes(app.UIFigure);
            title(app.LiveAngularVelFiltering, 'Live Angular Velocity Filtering')
            xlabel(app.LiveAngularVelFiltering, 'Time (seconds)')
            ylabel(app.LiveAngularVelFiltering, 'rad/s')
            zlabel(app.LiveAngularVelFiltering, 'Z')
            app.LiveAngularVelFiltering.ButtonDownFcn = createCallbackFcn(app, @LiveAngularVelFilteringButtonDown, true);
            app.ModelFitCurrent = uiaxes(app.UIFigure);
            title(app.ModelFitCurrent, 'Model Vs. Filtered Current (I)')
            xlabel(app.ModelFitCurrent, 'Time (seconds)')
            ylabel(app.ModelFitCurrent, 'Amplitude')
            zlabel(app.ModelFitCurrent, 'Z')
            app.ModelFitCurrent.ButtonDownFcn = createCallbackFcn(app, @ModelFitCurrentButtonDown, true);
            app.ModelFitAngularVel = uiaxes(app.UIFigure);
            title(app.ModelFitAngularVel, 'Model Vs. Filtered Angular Velocity (W)')
            xlabel(app.ModelFitAngularVel, 'Time (seconds)')
            ylabel(app.ModelFitAngularVel, 'Amplitude')
            zlabel(app.ModelFitAngularVel, 'Z')
            app.ModelFitAngularVel.ButtonDownFcn = createCallbackFcn(app, @ModelFitAngularVelButtonDown, true);
            app.TsmsEditFieldLabel = uilabel(app.UIFigure);
            app.TsmsEditFieldLabel.Text = 'Ts (ms)';
            app.TsmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TsmsEditField.ValueChangedFcn = createCallbackFcn(app, @TsmsEditFieldValueChanged, true);
            app.VoltageinEditFieldLabel = uilabel(app.UIFigure);
            app.VoltageinEditFieldLabel.Text = 'Voltage in';
            app.VoltageinEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartLogButton = uibutton(app.UIFigure, 'push');
            app.StartLogButton.ButtonPushedFcn = createCallbackFcn(app, @StartLogButtonPushed, true);
            app.StartLogButton.Text = 'Start Log';
            app.StartFilterButton = uibutton(app.UIFigure, 'push');
            app.StartFilterButton.ButtonPushedFcn = createCallbackFcn(app, @StartFilterButtonPushed, true);
            app.StartFilterButton.Text = 'Start Filter';
            app.DoneButtonCurrent = uibutton(app.UIFigure, 'push');
            app.DoneButtonCurrent.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonCurrentPushed, true);
            app.DoneButtonCurrent.Text = 'Done';
            app.DoneButtonAngularVel = uibutton(app.UIFigure, 'push');
            app.DoneButtonAngularVel.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonAngularVelPushed, true);
            app.DoneButtonAngularVel.Text = 'Done';
            app.BuildSSButton = uibutton(app.UIFigure, 'push');
            app.BuildSSButton.ButtonPushedFcn = createCallbackFcn(app, @BuildSSButtonPushed, true);
            app.BuildSSButton.Text = 'Build SS';
            app.continuoustimestatespacemodelLabel = uilabel(app.UIFigure);
            app.continuoustimestatespacemodelLabel.Text = 'State-space model';
            app.StatespacemodelTextArea = uitextarea(app.UIFigure);
            app.StatespacemodelTextArea.ValueChangedFcn = createCallbackFcn(app, @StatespacemodelTextAreaValueChanged, true);
            app.CurrentIfilterCutofffrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentIfilterCutofffrequencyEditFieldLabel.Text = {'Current (I) filter '; 'Cutoff frequency'};
            app.CurrentIfilterCutofffrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentIfilterCutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentIfilterCutofffrequencyEditFieldValueChanged, true);
            app.AngularvelocidtywfilterCutofffrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.AngularvelocidtywfilterCutofffrequencyEditFieldLabel.Text = {'Angular velocidty (w)'; 'filter Cutoff frequency'};
            app.AngularvelocidtywfilterCutofffrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngularvelocidtywfilterCutofffrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @AngularvelocidtywfilterCutofffrequencyEditFieldValueChanged, true);
            app.PWMlistEditField_2Label = uilabel(app.UIFigure);
            app.PWMlistEditField_2Label.Text = 'PWM list';
            app.PWMlistEditField = uieditfield(app.UIFigure, 'text');
            app.PWMlistEditField.ValueChangedFcn = createCallbackFcn(app, @PWMlistEditFieldValueChanged, true);
            app.COMSelectorDropDownLabel = uilabel(app.UIFigure);
            app.COMSelectorDropDownLabel.Text = 'COM Selector';
            app.COMSelectorDropDown = uidropdown(app.UIFigure);
            app.RefreshCOMButton = uibutton(app.UIFigure, 'push');
            app.RefreshCOMButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMButtonPushed, true);
            app.RefreshCOMButton.Text = 'Refresh COM';
            app.ExportPNGsandLogsButton = uibutton(app.UIFigure, 'push');
            app.ExportPNGsandLogsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPNGsandLogsButtonPushed, true);
            app.ExportPNGsandLogsButton.Text = 'Export PNGs and Logs';
            app.CDMotorSystemIdentificationforStateSpacebuildingLabel = uilabel(app.UIFigure);
            app.CDMotorSystemIdentificationforStateSpacebuildingLabel.Text = {'CD Motor System Identification'; 'for State Space building'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CDmotor_SS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end